classdef ExponentaApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ExponentaAppUIFigure  matlab.ui.Figure
        TabGroup              matlab.ui.container.TabGroup
        Tab                   matlab.ui.container.Tab
        Exponentaru           matlab.ui.control.Button
        Docs                  matlab.ui.control.Button
        MATLABInRussia        matlab.ui.control.Button
        Hub                   matlab.ui.control.Button
        Ask                   matlab.ui.control.Button
        Help                  matlab.ui.control.Button
        Update                matlab.ui.control.Button
        Settings              matlab.ui.control.Button
        Tab_4                 matlab.ui.container.Tab
        Tab_3                 matlab.ui.container.Tab
        MATLABIntro           matlab.ui.control.Button
        SimulinkIntro         matlab.ui.control.Button
        MATLABInRussia_4      matlab.ui.control.Button
        ListBox               matlab.ui.control.ListBox
        MATLABInRussia_5      matlab.ui.control.Button
        Label_2               matlab.ui.control.Label
        Tab_2                 matlab.ui.container.Tab
        Trial                 matlab.ui.control.Button
        Price                 matlab.ui.control.Button
        Support               matlab.ui.control.Button
        Question              matlab.ui.control.Button
        LicenseLabel          matlab.ui.control.Label
        License               matlab.ui.control.EditField
        Support_2             matlab.ui.control.Button
        Tab_5                 matlab.ui.container.Tab
        MATLABIntro_2         matlab.ui.control.Button
    end

    
    properties (Access = private)
        Updater % ToolboxUpdater
        Notifier % ExponentaNotifier
    end
    
    methods (Access = private)
        
        function Notify(app, data)
            if isempty(data)
                num = 0;
            else
                num = nnz(~data.read);
            end
            if num > 0
                title = "События (" + num + ")";
            else
                title = "События";
            end
            app.TabGroup.Children(2).Title = title;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Updater = ExponentaUpdater();
            app.License.Value = license;
            if ~app.Updater.ext.isfavs()
                app.Updater.ext.addfav('Документация', 'rusdoc', 'icon-mw-documentation.png');
                app.Updater.ext.addfav('Сообщество', 'hub', 'icon-mat-people.png');
                app.Updater.ext.addfav('Exponenta App', 'ExponentaApp', 'icon-my-etmc-exponenta.png');
            end
            app.Notifier = ExponentaNotifier(@app.Notify);
            w = Async();
            w.addTask(@(~,~)uisnackbar(app.ExponentaAppUIFigure,...
                "Доступна новая версия Exponenta Toolbox" + newline + "Рекомендуем обновиться",...
                'Theme', 'warning', 'Time', 60), 3);
            w.start();
        end

        % Button pushed function: Exponentaru
        function ExponentaruPushed(app, event)
            exponenta
        end

        % Button pushed function: Docs
        function DocsPushed(app, event)
            rusdoc
        end

        % Button pushed function: MATLABInRussia
        function MATLABInRussiaPushed(app, event)
            matlabinrussia
        end

        % Button pushed function: Hub
        function HubButtonPushed(app, event)
            hub
        end

        % Button pushed function: Ask
        function AskButtonPushed(app, event)
            ask
        end

        % Callback function
        function MatlabruButtonPushed(app, event)
            matlabru
        end

        % Button pushed function: Help
        function HelpButtonPushed(app, event)
            ExponentaDoc
        end

        % Button pushed function: Support
        function SupportButtonPushed(app, event)
            exponenta.getsupport
        end

        % Button pushed function: MATLABIntro
        function MATLABIntroButtonPushed(app, event)
            exponenta.matlabintro
        end

        % Button pushed function: SimulinkIntro
        function SimulinkIntroButtonPushed(app, event)
            exponenta.simulinkintro
        end

        % Button pushed function: Settings
        function SettingsButtonPushed(app, event)
            i = uiinput(app.ExponentaAppUIFigure, 'Test');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ExponentaAppUIFigure = uifigure('Visible', 'off');
            app.ExponentaAppUIFigure.Name = 'Exponenta App';
            app.TabGroup = uitabgroup(app.ExponentaAppUIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Главная';
            app.Exponentaru = uibutton(app.Tab, 'push');
            app.Exponentaru.ButtonPushedFcn = createCallbackFcn(app, @ExponentaruPushed, true);
            app.Exponentaru.Icon = 'icon-my-etmc-exponenta.png';
            app.Exponentaru.Text = 'ЦИТМ "Экспонента"';
            app.Docs = uibutton(app.Tab, 'push');
            app.Docs.ButtonPushedFcn = createCallbackFcn(app, @DocsPushed, true);
            app.Docs.Icon = 'icon-mw-documentation.png';
            app.Docs.Text = 'Документация на русском';
            app.MATLABInRussia = uibutton(app.Tab, 'push');
            app.MATLABInRussia.ButtonPushedFcn = createCallbackFcn(app, @MATLABInRussiaPushed, true);
            app.MATLABInRussia.Icon = 'icon-fa-youtube-play.png';
            app.MATLABInRussia.Text = 'Видео (MATLABInRussia)';
            app.Hub = uibutton(app.Tab, 'push');
            app.Hub.ButtonPushedFcn = createCallbackFcn(app, @HubButtonPushed, true);
            app.Hub.Icon = 'icon-mat-people.png';
            app.Hub.Text = 'Сообщество';
            app.Ask = uibutton(app.Tab, 'push');
            app.Ask.ButtonPushedFcn = createCallbackFcn(app, @AskButtonPushed, true);
            app.Ask.Icon = 'icon-mw-answers.png';
            app.Ask.Text = 'Спросить';
            app.Help = uibutton(app.Tab, 'push');
            app.Help.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.Help.Icon = 'icon-mat-help-outline.png';
            app.Help.Tooltip = {'Справка'};
            app.Help.Text = '';
            app.Update = uibutton(app.Tab, 'push');
            app.Update.Icon = 'icon-my-update-daa520.png';
            app.Update.Tooltip = {'Обновить'};
            app.Update.Text = {'Установить'; 'обновление'};
            app.Settings = uibutton(app.Tab, 'push');
            app.Settings.ButtonPushedFcn = createCallbackFcn(app, @SettingsButtonPushed, true);
            app.Settings.Icon = 'icon-mat-settings.png';
            app.Settings.Tooltip = {'Настройки'};
            app.Settings.Text = '';
            app.Tab_4 = uitab(app.TabGroup);
            app.Tab_4.Title = 'События';
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = 'Обучение';
            app.MATLABIntro = uibutton(app.Tab_3, 'push');
            app.MATLABIntro.ButtonPushedFcn = createCallbackFcn(app, @MATLABIntroButtonPushed, true);
            app.MATLABIntro.Icon = 'icon-mw-matlab.png';
            app.MATLABIntro.Text = 'Введение в MATLAB';
            app.SimulinkIntro = uibutton(app.Tab_3, 'push');
            app.SimulinkIntro.ButtonPushedFcn = createCallbackFcn(app, @SimulinkIntroButtonPushed, true);
            app.SimulinkIntro.Icon = 'icon-mw-simulink-alt.png';
            app.SimulinkIntro.Text = 'Введение в Simulink';
            app.MATLABInRussia_4 = uibutton(app.Tab_3, 'push');
            app.MATLABInRussia_4.Icon = 'icon-my-edu-student-hat.png';
            app.MATLABInRussia_4.Text = 'Тренинги';
            app.ListBox = uilistbox(app.Tab_3);
            app.ListBox.Items = {'Анализ данных', 'Цифровая обработка сигналов', 'Физическое моделирование', 'Глубокое обучение'};
            app.ListBox.Value = 'Анализ данных';
            app.MATLABInRussia_5 = uibutton(app.Tab_3, 'push');
            app.MATLABInRussia_5.Icon = 'icon-fa-youtube-play.png';
            app.MATLABInRussia_5.Text = 'Открыть';
            app.Label_2 = uilabel(app.Tab_3);
            app.Label_2.Text = 'Дополнительно';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = 'Поддержка';
            app.Trial = uibutton(app.Tab_2, 'push');
            app.Trial.Icon = 'icon-mat-av-timer.png';
            app.Trial.Text = 'Запросить пробную версию';
            app.Price = uibutton(app.Tab_2, 'push');
            app.Price.Icon = 'icon-mat-attach-money.png';
            app.Price.Text = 'Запросить цены';
            app.Support = uibutton(app.Tab_2, 'push');
            app.Support.ButtonPushedFcn = createCallbackFcn(app, @SupportButtonPushed, true);
            app.Support.Icon = 'icon-mw-support.png';
            app.Support.Text = 'Запросить техподдержку';
            app.Question = uibutton(app.Tab_2, 'push');
            app.Question.Icon = 'icon-mat-mail-outline.png';
            app.Question.Text = 'Связаться по другому вопросу';
            app.LicenseLabel = uilabel(app.Tab_2);
            app.LicenseLabel.Text = 'При обращении предоставьте номер лицензии:';
            app.License = uieditfield(app.Tab_2, 'text');
            app.License.Editable = 'off';
            app.Support_2 = uibutton(app.Tab_2, 'push');
            app.Support_2.Icon = 'icon-my-etmc-exponenta.png';
            app.Support_2.Text = 'Оставить отзыв об Exponenta Toolbox';
            app.Tab_5 = uitab(app.TabGroup);
            app.Tab_5.Title = 'Проекты';
            app.MATLABIntro_2 = uibutton(app.Tab_5, 'push');
            app.MATLABIntro_2.Icon = 'icon-fa-github.png';
            app.MATLABIntro_2.Text = 'Наши открытые проекты';
            % Show the figure after all components are created
            app.ExponentaAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ExponentaApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ExponentaAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ExponentaAppUIFigure)
        end
    end
end